classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        XEditFieldLabel           matlab.ui.control.Label
        xInput                    matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        yInput                    matlab.ui.control.NumericEditField
        ClickforcoordinateButton  matlab.ui.control.Button
        CalibrationpointsButton   matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        GPSCoordinatesLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: yInput
        function yInputValueChanged(app, event)
            yValue = double(app.yInput.Value);
            
        end

        % Value changed function: xInput
        function xInputValueChanged(app, event)
            xValue = double(app.xInput.Value);
            
        end

        % Button pushed function: ClickforcoordinateButton
        function ClickforcoordinateButtonPushed(app, event)
            xl = [0,1653];
            yl = [0,1559];
            im = imread('CampusMap.png');
            h = image(xl, flip(yl), im);impixelinfo,
            ax = gca;
            ax.YDir = 'normal';
            uistack(h, 'bottom');
            
            
            x = app.xInput.Value;
            y = app.yInput.Value;
            
            hold on;
            plot(x,y, 'o', 'MarkerFaceColor', 'r', 'MarkerSize', 6);
            grid on;
        end

        % Button pushed function: CalibrationpointsButton
        function CalibrationpointsButtonPushed(app, event)
             xl = [0,1653];
            yl = [0,1559];
            im = imread('CampusMap.png');
            h = image(xl, flip(yl), im);impixelinfo,
            ax = gca;
            ax.YDir = 'normal';
            uistack(h, 'bottom');
            
            
            x = [100, 1050, 564];
            y = [1500, 192, 1242];
            
            hold on;
            plot(x,y, 'o', 'MarkerFaceColor', 'r', 'MarkerSize', 6);
            grid on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.xInput = uieditfield(app.UIFigure, 'numeric');
            app.xInput.ValueChangedFcn = createCallbackFcn(app, @xInputValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.yInput = uieditfield(app.UIFigure, 'numeric');
            app.yInput.ValueChangedFcn = createCallbackFcn(app, @yInputValueChanged, true);
            app.ClickforcoordinateButton = uibutton(app.UIFigure, 'push');
            app.ClickforcoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @ClickforcoordinateButtonPushed, true);
            app.ClickforcoordinateButton.Text = 'Click for coordinate';
            app.CalibrationpointsButton = uibutton(app.UIFigure, 'push');
            app.CalibrationpointsButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationpointsButtonPushed, true);
            app.CalibrationpointsButton.Text = 'Calibration points';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'CampusMap.png';
            app.GPSCoordinatesLabel = uilabel(app.UIFigure);
            app.GPSCoordinatesLabel.Text = 'GPS Coordinates';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end